classdef MICA_LEPINE_MATHET_R2016_compatible < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MICAUI                          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportsignalTab                 matlab.ui.container.Tab
        InstructionsLabel               matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        Logo                            matlab.ui.control.UIAxes
        AnalysisParametersTab           matlab.ui.container.Tab
        IntervalChoiceAxes              matlab.ui.control.UIAxes
        LowerboundSliderLabel           matlab.ui.control.Label
        LowerBoundSlider                matlab.ui.control.Slider
        UpperboundSliderLabel           matlab.ui.control.Label
        UpperBoundSlider                matlab.ui.control.Slider
        PatientCategoryButtonGroup      matlab.ui.container.ButtonGroup
        AdultButton                     matlab.ui.control.ToggleButton
        ChildButton                     matlab.ui.control.ToggleButton
        BabyButton                      matlab.ui.control.ToggleButton
        PathologiesanalysisSwitchLabel  matlab.ui.control.Label
        PathologiesSwitch               matlab.ui.control.Switch
        HighlightPQRSTpositionsSwitchLabel  matlab.ui.control.Label
        PQRSTSwitch                     matlab.ui.control.Switch
        CutSignalButton                 matlab.ui.control.Button
        ResetViewButton                 matlab.ui.control.Button
        StartAnalysisButtonButton       matlab.ui.control.Button
        AnalysisResultTab               matlab.ui.container.Tab
        AnalysedSignalAxes              matlab.ui.control.UIAxes
        GeneralPanel                    matlab.ui.container.Panel
        AvgBPMRateLabel                 matlab.ui.control.Label
        BPMField                        matlab.ui.control.NumericEditField
        AnalysedcomplexesLabel          matlab.ui.control.Label
        NbComplexesField                matlab.ui.control.NumericEditField
        AvgPRintervalsLabel             matlab.ui.control.Label
        AvgPRField                      matlab.ui.control.NumericEditField
        AvgQRSintervalsLabel            matlab.ui.control.Label
        AvgQRSField                     matlab.ui.control.NumericEditField
        AvgQTintervalsLabel             matlab.ui.control.Label
        AvgQTField                      matlab.ui.control.NumericEditField
        AvgSTintervalsLabel             matlab.ui.control.Label
        AvgSTField                      matlab.ui.control.NumericEditField
        PathologiesPanel                matlab.ui.container.Panel
        TachycardiaLampLabel            matlab.ui.control.Label
        TachycardiaLamp                 matlab.ui.control.Lamp
        BradycardiaLampLabel            matlab.ui.control.Label
        BradycardiaLamp                 matlab.ui.control.Lamp
        EctopicLabel                    matlab.ui.control.Label
        EctopicField                    matlab.ui.control.NumericEditField
        EctopicLamp                     matlab.ui.control.Lamp
        AtrialfibrillationLampLabel     matlab.ui.control.Label
        AtrialFibrilationLamp           matlab.ui.control.Lamp
        VentricularfibrillationLampLabel  matlab.ui.control.Label
        VentricularFibrilationLamp      matlab.ui.control.Lamp
        OutputPanel                     matlab.ui.container.Panel
        ExportDataButton                matlab.ui.control.Button
        ExportAnalysisButton            matlab.ui.control.Button
        PersonalobservationsTextAreaLabel  matlab.ui.control.Label
        PersonalobservationsTextArea    matlab.ui.control.TextArea
        TitleText                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImportedECG % raw imported data
        ECG % treated data
        ImportedFs  % sampling frequency
        N % number of samples in ECG
        time_axis % x axis transformed in time (seconds)
        P_locs
        Q_locs
        R_locs
        S_locs
        T_locs
        Avg_BPM
        Alert_BPM
        Alert_Ectopic
    end
    
    methods (Access = private)
        
        function DisableAnalysisParameters(app) % disable second page
            app.IntervalChoiceAxes.Color = [0.9 0.9 0.9];
            app.LowerBoundSlider.Enable = 'off';
            app.UpperBoundSlider.Enable = 'off';
            app.AdultButton.Enable = 'off';
            app.ChildButton.Enable = 'off';
            app.BabyButton.Enable = 'off';
            app.PathologiesSwitch.Enable = 'off';
            app.PQRSTSwitch.Enable = 'off';
            app.CutSignalButton.Enable = 'off';
            app.ResetViewButton.Enable = 'off';
            app.StartAnalysisButtonButton.Enable = 'off';
        end
        
        function EnableAnalysisParameters(app) % enable second page
            app.IntervalChoiceAxes.Color = [1 1 1];
            app.LowerBoundSlider.Enable = 'on';
            app.UpperBoundSlider.Enable = 'on';
            app.AdultButton.Enable = 'on';
            app.ChildButton.Enable = 'on';
            app.BabyButton.Enable = 'on';
            app.PathologiesSwitch.Enable = 'on';
            app.PQRSTSwitch.Enable = 'on';
            app.CutSignalButton.Enable = 'on';
            app.ResetViewButton.Enable = 'on';
            app.StartAnalysisButtonButton.Enable = 'on';
        end
        
        function DisableAnalysisResult(app) % disable third page
            app.AnalysedSignalAxes.Color = [0.9 0.9 0.9];
            app.BPMField.Enable = 'off';
            app.NbComplexesField.Enable = 'off';
            app.AvgPRField.Enable = 'off';
            app.AvgQRSField.Enable = 'off';
            app.AvgQTField.Enable = 'off';
            app.AvgSTField.Enable = 'off';
            app.TachycardiaLamp.Color = [0.9 0.9 0.9];
            app.BradycardiaLamp.Color = [0.9 0.9 0.9];
            app.EctopicField.Enable = 'off';
            app.EctopicLamp.Color = [0.9 0.9 0.9];
            app.AtrialFibrilationLamp.Color = [0.9 0.9 0.9];
            app.VentricularFibrilationLamp.Color = [0.9 0.9 0.9];
            app.ExportDataButton.Enable = 'off';
            app.ExportAnalysisButton.Enable = 'off';
            app.PersonalobservationsTextArea.Editable = 'off';
        end
        
        function EnableAnalysisResult(app) % enable third page
            app.AnalysedSignalAxes.Color = [1 1 1];
            app.BPMField.Enable = 'on';
            app.NbComplexesField.Enable = 'on';
            app.AvgPRField.Enable = 'on';
            app.AvgQRSField.Enable = 'on';
            app.AvgQTField.Enable = 'on';
            app.AvgSTField.Enable = 'on';
            app.TachycardiaLamp.Color = [1 1 1];
            app.BradycardiaLamp.Color = [1 1 1];
            app.EctopicField.Enable = 'on';
            app.EctopicLamp.Color = [1 1 1];
            app.AtrialFibrilationLamp.Color = [1 1 1];
            app.VentricularFibrilationLamp.Color = [1 1 1];
            app.ExportDataButton.Enable = 'on';
            app.PersonalobservationsTextArea.Editable = 'on';
        end
        
        function PlotIntervalHighlight(app)
            app.N = length(app.ECG);
            app.time_axis = (1:app.N)/app.ImportedFs;
            time_interval_start = round(app.LowerBoundSlider.Value/app.ImportedFs);
            time_interval_end = round(app.UpperBoundSlider.Value/app.ImportedFs);
            
            % Plot ECG
            app.IntervalChoiceAxes.NextPlot = 'replacechildren';
            plot(app.IntervalChoiceAxes, app.time_axis, app.ECG);
            
            % Plot left vertical line
            app.IntervalChoiceAxes.NextPlot = 'add';
            plot(app.IntervalChoiceAxes, [time_interval_start time_interval_start], ...
                [app.IntervalChoiceAxes.YLim(1), app.IntervalChoiceAxes.YLim(2)], ...
                'LineWidth', 3, 'LineStyle', '--', 'Color', [0.85 0.33 0.10]);
            
            % Plot right horizontal line
            plot(app.IntervalChoiceAxes, [time_interval_end time_interval_end], ...
                [app.IntervalChoiceAxes.YLim(1), app.IntervalChoiceAxes.YLim(2)], ...
                'LineWidth', 3, 'LineStyle', '--', 'Color', [0.85 0.33 0.10]);
            
            % Set XLim (with litlle offsets of +1/-1 to see lines even on edges)
            app.IntervalChoiceAxes.XLimMode = 'manual';
            app.IntervalChoiceAxes.XLim = [0, app.time_axis(end)+1];
        end
        
        function PlotPQRSTPositions(app)
            app.AnalysedSignalAxes.NextPlot = 'add';
            plot(app.AnalysedSignalAxes, app.time_axis(app.P_locs),app.ECG(app.P_locs), '*','Color','red');
            
            plot(app.AnalysedSignalAxes, app.time_axis(app.Q_locs),app.ECG(app.Q_locs), '*','Color','blue');
            plot(app.AnalysedSignalAxes, app.time_axis(app.R_locs),app.ECG(app.R_locs), '*','Color','magenta');
            plot(app.AnalysedSignalAxes, app.time_axis(app.S_locs),app.ECG(app.S_locs), '*','Color','green');
            plot(app.AnalysedSignalAxes, app.time_axis(app.T_locs),app.ECG(app.T_locs), '*','Color','black');
            
            legend(app.AnalysedSignalAxes, 'ECG', 'P', 'Q', 'R', 'S', 'T', ...
                'Location', 'southoutside', 'Orientation', 'horizontal');
        end
        
        function DetectPathologies(app)
            app.ExportAnalysisButton.Enable = 'on';
            
            % Tachychardia/Bradychardia
            if (app.AdultButton.Value == 1)
                patient_category = 1;
            elseif(app.ChildButton.Value == 1)
                patient_category = 2;
            else
                patient_category = 3;
            end
            
            UpdateTachyBrady(app, patient_category);
            
            % Ectopic beats
            nb_complexes = length(app.R_locs);
            [ mean_RR, PVC_count ] = ectopicbeat( app.R_locs );
            app.EctopicField.Value = PVC_count;
            if(PVC_count > 0 && PVC_count < nb_complexes*0.15) % occur rarely
                app.EctopicLamp.Color = [0.89 0.69 0.13]; % orange
                app.Alert_Ectopic=1; % for export
            elseif(PVC_count >= nb_complexes*0.15) % occur often (15%)
                app.EctopicLamp.Color = [1.0 0 0]; % red
                app.Alert_Ectopic=2; % for export
            else
                app.EctopicLamp.Color = [0 1.0 0]; % green
                app.Alert_Ectopic=0;
            end
        end
        
        function UpdateAnalysisInfos(app, Avg_PR, Avg_QRS, Avg_QT, Avg_ST)
            app.BPMField.Value = app.Avg_BPM; % Update avg BPM display
            app.NbComplexesField.Value = length(app.R_locs); % count nb of complexes analysed
            app.AvgPRField.Value = Avg_PR; % PR avg interval (in s)
            app.AvgQRSField.Value = Avg_QRS; % QR avg interval (in s)
            app.AvgQTField.Value = Avg_QT; % QT avg interval (in s)
            app.AvgSTField.Value = Avg_ST; % ST avg interval (in s)
        end
        
        function UpdateTachyBrady(app, patient_category)
            [ res ] = tachycardia( app.R_locs, patient_category, app.Avg_BPM );
            app.Alert_BPM=0;
            switch(res)
                case -2
                    app.TachycardiaLamp.Color = [0 1.0 0]; % green
                    app.BradycardiaLamp.Color = [1.0 0 0]; % red
                    app.Alert_BPM=2; % for export
                case -1
                    app.TachycardiaLamp.Color = [0 1.0 0]; % green
                    app.BradycardiaLamp.Color = [0.89 0.69 0.13]; % orange
                    app.Alert_BPM=1; % for export
                case 1
                    app.TachycardiaLamp.Color = [0.89 0.69 0.13]; % orange
                    app.BradycardiaLamp.Color = [0 1.0 0]; % green
                    app.Alert_BPM=1; % for export
                case 2
                    app.TachycardiaLamp.Color = [1.0 0 0]; % red
                    app.BradycardiaLamp.Color = [0 1.0 0]; % green
                    app.Alert_BPM=2; % for export
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('src'); % Add functions in 'src' folders to the path
            
            DisableAnalysisParameters(app);
            DisableAnalysisResult(app);
            logo = imread('src/ecg.png');
            image(logo, 'Parent', app.Logo);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Import ECG
            import_success = true;
            try
                [file,path] = uigetfile('*.mat', 'Import ECG');
                signal = load(fullfile(path, file));
                app.ImportedECG = signal.ecg;
                app.ImportedFs = signal.Fs;
            catch
                msgbox('Error : please provide an .mat ECG with variables named "ecg" and "Fs".', 'Import failed','error');
                import_success = false;
            end
            
            if(import_success)
                % Prepare Analysis Parameters screen
                EnableAnalysisParameters(app);
                app.InstructionsLabel.Text = 'Signal imported.';
                
                app.ECG = app.ImportedECG; % we want to keep original signal to be able to reset the view
                
                app.LowerBoundSlider.Limits = [1 length(app.ECG)]; % update sliders & intervals
                app.UpperBoundSlider.Limits = [1 length(app.ECG)];
                app.LowerBoundSlider.Value = 1;
                app.UpperBoundSlider.Value = length(app.ECG);
                CutSignalButtonPushed(app);
                
                PlotIntervalHighlight(app); % highlight interval
                app.TabGroup.SelectedTab = app.AnalysisParametersTab; %change tab
            end
            
        end

        % Value changed function: LowerBoundSlider, UpperBoundSlider
        function BoundSliderValueChanged(app, event)
            % Check if sliders positions are coherent
            if(app.LowerBoundSlider.Value >= app.UpperBoundSlider.Value)
                % reset sliders
                app.LowerBoundSlider.Value = 1;
                app.UpperBoundSlider.Value = length(app.ECG);
            end
            
            % If everything is okay we update the graph
            PlotIntervalHighlight(app);
            
            % But also we remove potential analysis result
            DisableAnalysisResult(app);
        end

        % Button pushed function: CutSignalButton
        function CutSignalButtonPushed(app, event)
            % Restrict signal
            app.ECG = app.ECG(round(app.LowerBoundSlider.Value) : round(app.UpperBoundSlider.Value));
            app.N = length(app.ECG);
            app.time_axis = (1:app.N)/app.ImportedFs;
            app.LowerBoundSlider.Limits = [1 length(app.ECG)]; % set sliders in sample units
            app.UpperBoundSlider.Limits = [1 length(app.ECG)];
            app.LowerBoundSlider.Value = 1;
            app.UpperBoundSlider.Value = length(app.ECG);
            
            PlotIntervalHighlight(app); % highlight interval
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            % Restore original imported signal
            app.ECG = app.ImportedECG; % restore original ECG
            % restore sliders & time_axis
            app.N = length(app.ECG);
            app.time_axis = (1:app.N)/app.ImportedFs;
            app.LowerBoundSlider.Limits = [1 length(app.ECG)]; % set sliders in sample units
            app.UpperBoundSlider.Limits = [1 length(app.ECG)];
            app.LowerBoundSlider.Value = 1;
            app.UpperBoundSlider.Value = length(app.ECG);
            
            PlotIntervalHighlight(app); % highlight interval
            DisableAnalysisResult(app); % remove analysis result
        end

        % Button pushed function: StartAnalysisButtonButton
        function StartAnalysisButtonButtonPushed(app, event)
            % cut to previously selected interval
            CutSignalButtonPushed(app); % cut to previously selected interval
            
            % prepare analysis result window
            EnableAnalysisResult(app);
            
            % change tab
            app.TabGroup.SelectedTab = app.AnalysisResultTab;
            
            % plotting the selected interval in analysis window
            app.AnalysedSignalAxes.NextPlot = 'replacechildren';
            plot(app.AnalysedSignalAxes, app.time_axis, app.ECG);
            app.AnalysedSignalAxes.XLimMode = 'manual';
            app.AnalysedSignalAxes.XLim = [1, app.time_axis(end)];
            
            % Calculte locs
            [ app.Q_locs, app.R_locs, app.S_locs ] = QRS_loc( app.ECG, app.ImportedFs );
            [ app.P_locs, app.T_locs ] = PT_loc( app.ECG, app.R_locs );
            
            % Calculate avg BPM rate
            app.Avg_BPM = BPM(app.R_locs);
            
            % Calculte avg intervals
            length_avg_intevals = min([length(app.Q_locs) length(app.R_locs) length(app.S_locs) length(app.P_locs) length(app.T_locs)]);
            Avg_PR = mean(app.R_locs(1:length_avg_intevals)-app.P_locs(1:length_avg_intevals))/app.ImportedFs;
            Avg_QRS = mean(app.S_locs(1:length_avg_intevals)-app.Q_locs(1:length_avg_intevals))/app.ImportedFs;
            Avg_QT = mean(app.T_locs(1:length_avg_intevals)-app.Q_locs(1:length_avg_intevals))/app.ImportedFs;
            Avg_ST= mean(app.T_locs(1:length_avg_intevals)-app.S_locs(1:length_avg_intevals))/app.ImportedFs;
            
            
            % Plot PQRST positions (if asked)
            if(app.PQRSTSwitch.Value == "On")
                PlotPQRSTPositions(app);
            end
            
            % Detect pathologies (if asked)
            if(app.PathologiesSwitch.Value == "On")
                DetectPathologies(app);
            end
            
            % Update general analysis infos
            UpdateAnalysisInfos(app, Avg_PR, Avg_QRS, Avg_QT, Avg_ST);
            
            % Update output panel
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            try
                [file,path] = uiputfile('*.mat', 'Export selected part of ECG');
                export = app.ECG;
                save(fullfile(path, file), 'export');
            catch
                msgbox('Error : unable to write file.', 'Export failed','error');
            end
        end

        % Button pushed function: ExportAnalysisButton
        function ExportAnalysisButtonPushed(app, event)
            try
                [file,path] = uiputfile('*.txt', 'Export result of analysis');
                fileID = fopen(fullfile(path, file), 'w');
                fprintf(fileID, 'MICA Analysis Result \nWARNING : this is the result of an assistant, please be careful with those results\n\n');
                
                fprintf(fileID, 'Average BPM : %i\n', app.BPMField.Value);
                if(app.Alert_BPM == 1)
                    fprintf(fileID, 'Suspicous BPM rate detected.\n');
                elseif(app.Alert_BPM == 2)
                    fprintf(fileID, 'DANGEROUS BPM rate detected.\n');
                end
                
                fprintf(fileID, 'Number of ectopic beats : %i\n', app.EctopicField.Value);
                if(app.Alert_Ectopic == 1)
                    fprintf(fileID, 'Few ectopic beats detected.\n');
                elseif(app.Alert_Ectopic == 2)
                    fprintf(fileID, 'DANGEROUS quantity of ectopic beats detected.\n');
                end
                fprintf(fileID, 'Personal analysis : %s\n', app.PersonalobservationsTextArea.Value{1:end});                
                fclose(fileID);
            catch
                msgbox('Error : unable to write file.', 'Export failed','error');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MICAUI = uifigure;
            app.MICAUI.Name = 'Matlab Implementation of a Cardiologist Assistant';
            app.TabGroup = uitabgroup(app.MICAUI);
            app.ImportsignalTab = uitab(app.TabGroup);
            app.ImportsignalTab.Title = 'Import signal';
            app.InstructionsLabel = uilabel(app.ImportsignalTab);
            app.InstructionsLabel.Text = 'Please import an electrocardiogram using the button below.';
            app.LoadButton = uibutton(app.ImportsignalTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load from file...';
            app.Logo = uiaxes(app.ImportsignalTab);
            app.Logo.XTick = [];
            app.Logo.YTick = [];
            app.Logo.YTickLabel = {'[ ]'};
            app.Logo.ZTick = [];
            app.AnalysisParametersTab = uitab(app.TabGroup);
            app.AnalysisParametersTab.Title = 'Analysis parameters';
            app.IntervalChoiceAxes = uiaxes(app.AnalysisParametersTab);
            title(app.IntervalChoiceAxes, 'Choice of interval for the analysis')
            xlabel(app.IntervalChoiceAxes, 'Time (s)')
            ylabel(app.IntervalChoiceAxes, 'Magnitude')
            app.IntervalChoiceAxes.XLim = [0 1];
            app.IntervalChoiceAxes.LineStyleOrder = {'-'};
            app.LowerboundSliderLabel = uilabel(app.AnalysisParametersTab);
            app.LowerboundSliderLabel.Text = 'Lower bound';
            app.LowerBoundSlider = uislider(app.AnalysisParametersTab);
            app.LowerBoundSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.LowerBoundSlider.ValueChangedFcn = createCallbackFcn(app, @BoundSliderValueChanged, true);
            app.LowerBoundSlider.MinorTicks = [];
            app.UpperboundSliderLabel = uilabel(app.AnalysisParametersTab);
            app.UpperboundSliderLabel.Text = 'Upper bound';
            app.UpperBoundSlider = uislider(app.AnalysisParametersTab);
            app.UpperBoundSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.UpperBoundSlider.ValueChangedFcn = createCallbackFcn(app, @BoundSliderValueChanged, true);
            app.UpperBoundSlider.MinorTicks = [];
            app.UpperBoundSlider.Value = 100;
            app.PatientCategoryButtonGroup = uibuttongroup(app.AnalysisParametersTab);
            app.PatientCategoryButtonGroup.Title = 'Patient category';
            app.AdultButton = uitogglebutton(app.PatientCategoryButtonGroup);
            app.AdultButton.Text = 'Adult';
            app.AdultButton.Value = true;
            app.ChildButton = uitogglebutton(app.PatientCategoryButtonGroup);
            app.ChildButton.Text = 'Child';
            app.BabyButton = uitogglebutton(app.PatientCategoryButtonGroup);
            app.BabyButton.Text = 'Baby';
            app.PathologiesanalysisSwitchLabel = uilabel(app.AnalysisParametersTab);
            app.PathologiesanalysisSwitchLabel.Text = 'Pathologies analysis';
            app.PathologiesSwitch = uiswitch(app.AnalysisParametersTab, 'slider');
            app.PathologiesSwitch.Value = 'On';
            app.HighlightPQRSTpositionsSwitchLabel = uilabel(app.AnalysisParametersTab);
            app.HighlightPQRSTpositionsSwitchLabel.Text = 'Highlight PQRST positions';
            app.PQRSTSwitch = uiswitch(app.AnalysisParametersTab, 'slider');
            app.PQRSTSwitch.Value = 'On';
            app.CutSignalButton = uibutton(app.AnalysisParametersTab, 'push');
            app.CutSignalButton.ButtonPushedFcn = createCallbackFcn(app, @CutSignalButtonPushed, true);
            app.CutSignalButton.Text = {'Cut signal with '; 'selected bounds'};
            app.ResetViewButton = uibutton(app.AnalysisParametersTab, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = {'Reset to '; 'original view'};
            app.StartAnalysisButtonButton = uibutton(app.AnalysisParametersTab, 'push');
            app.StartAnalysisButtonButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonButtonPushed, true);
            app.StartAnalysisButtonButton.Text = {'Start'; 'analysis'};
            app.AnalysisResultTab = uitab(app.TabGroup);
            app.AnalysisResultTab.Title = 'Analysis';
            app.AnalysedSignalAxes = uiaxes(app.AnalysisResultTab);
            title(app.AnalysedSignalAxes, 'Analysis of the selected portion of the signal')
            xlabel(app.AnalysedSignalAxes, 'Time (s)')
            ylabel(app.AnalysedSignalAxes, 'Magnitude')
            app.GeneralPanel = uipanel(app.AnalysisResultTab);
            app.GeneralPanel.Title = 'General infos';
            app.AvgBPMRateLabel = uilabel(app.GeneralPanel);
            app.AvgBPMRateLabel.Text = 'Avg BPM Rate';
            app.BPMField = uieditfield(app.GeneralPanel, 'numeric');
            app.BPMField.Limits = [0 Inf];
            app.BPMField.ValueDisplayFormat = '%.0f';
            app.BPMField.Editable = 'off';
            app.AnalysedcomplexesLabel = uilabel(app.GeneralPanel);
            app.AnalysedcomplexesLabel.Text = {'Analysed'; 'complexes'};
            app.NbComplexesField = uieditfield(app.GeneralPanel, 'numeric');
            app.NbComplexesField.Limits = [0 Inf];
            app.NbComplexesField.ValueDisplayFormat = '%.0f';
            app.NbComplexesField.Editable = 'off';
            app.AvgPRintervalsLabel = uilabel(app.GeneralPanel);
            app.AvgPRintervalsLabel.Text = {'Avg PR'; 'interval [s]'};
            app.AvgPRField = uieditfield(app.GeneralPanel, 'numeric');
            app.AvgPRField.ValueDisplayFormat = '%.3f';
            app.AvgPRField.Editable = 'off';
            app.AvgQRSintervalsLabel = uilabel(app.GeneralPanel);
            app.AvgQRSintervalsLabel.Text = {'Avg QRS'; 'interval [s]'};
            app.AvgQRSField = uieditfield(app.GeneralPanel, 'numeric');
            app.AvgQRSField.ValueDisplayFormat = '%.3f';
            app.AvgQRSField.Editable = 'off';
            app.AvgQTintervalsLabel = uilabel(app.GeneralPanel);
            app.AvgQTintervalsLabel.Text = {'Avg QT'; 'interval [s]'};
            app.AvgQTField = uieditfield(app.GeneralPanel, 'numeric');
            app.AvgQTField.ValueDisplayFormat = '%.3f';
            app.AvgQTField.Editable = 'off';
            app.AvgSTintervalsLabel = uilabel(app.GeneralPanel);
            app.AvgSTintervalsLabel.Text = {'Avg ST'; 'interval [s]'};
            app.AvgSTField = uieditfield(app.GeneralPanel, 'numeric');
            app.AvgSTField.ValueDisplayFormat = '%.3f';
            app.AvgSTField.Editable = 'off';
            app.PathologiesPanel = uipanel(app.AnalysisResultTab);
            app.PathologiesPanel.Title = 'Pathologies identification';
            app.TachycardiaLampLabel = uilabel(app.PathologiesPanel);
            app.TachycardiaLampLabel.Text = 'Tachycardia';
            app.TachycardiaLamp = uilamp(app.PathologiesPanel);
            app.BradycardiaLampLabel = uilabel(app.PathologiesPanel);
            app.BradycardiaLampLabel.Text = 'Bradycardia';
            app.BradycardiaLamp = uilamp(app.PathologiesPanel);
            app.EctopicLabel = uilabel(app.PathologiesPanel);
            app.EctopicLabel.Text = 'Ectopic beats';
            app.EctopicField = uieditfield(app.PathologiesPanel, 'numeric');
            app.EctopicField.Limits = [0 Inf];
            app.EctopicField.ValueDisplayFormat = '%.0f';
            app.EctopicField.Editable = 'off';
            app.EctopicLamp = uilamp(app.PathologiesPanel);
            app.AtrialfibrillationLampLabel = uilabel(app.PathologiesPanel);
            app.AtrialfibrillationLampLabel.Text = 'Atrial fibrillation';
            app.AtrialFibrilationLamp = uilamp(app.PathologiesPanel);
            app.VentricularfibrillationLampLabel = uilabel(app.PathologiesPanel);
            app.VentricularfibrillationLampLabel.Text = 'Ventricular fibrillation';
            app.VentricularFibrilationLamp = uilamp(app.PathologiesPanel);
            app.OutputPanel = uipanel(app.AnalysisResultTab);
            app.OutputPanel.Title = 'Output';
            app.ExportDataButton = uibutton(app.OutputPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = {'Export selected '; 'data'};
            app.ExportAnalysisButton = uibutton(app.OutputPanel, 'push');
            app.ExportAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAnalysisButtonPushed, true);
            app.ExportAnalysisButton.Text = {'Export analysis'; 'result'};
            app.PersonalobservationsTextAreaLabel = uilabel(app.OutputPanel);
            app.PersonalobservationsTextAreaLabel.Text = 'Personal observations';
            app.PersonalobservationsTextArea = uitextarea(app.OutputPanel);
            app.TitleText = uilabel(app.MICAUI);
            app.TitleText.Text = 'MICA Project by LEPINE Théo & MATHET Julien';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MICA_LEPINE_MATHET_R2016_compatible

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MICAUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MICAUI)
        end
    end
end